classdef ACV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ACVproject              matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        RESOLUTIONSTab          matlab.ui.container.Tab
        ResolutionsPanel        matlab.ui.container.Panel
        dXEditField_2Label      matlab.ui.control.Label
        dXEditField_2           matlab.ui.control.NumericEditField
        dXEditFieldLabel        matlab.ui.control.Label
        dXEditField             matlab.ui.control.NumericEditField
        dYEditFieldLabel        matlab.ui.control.Label
        dYEditField             matlab.ui.control.NumericEditField
        dPsiEditFieldLabel      matlab.ui.control.Label
        dPsiEditField           matlab.ui.control.NumericEditField
        dBEditFieldLabel        matlab.ui.control.Label
        dBEditField             matlab.ui.control.NumericEditField
        drEditFieldLabel        matlab.ui.control.Label
        drEditField             matlab.ui.control.NumericEditField
        RcarEditFieldLabel      matlab.ui.control.Label
        RcarEditField           matlab.ui.control.NumericEditField
        BoundsPanel             matlab.ui.container.Panel
        X_minEditFieldLabel     matlab.ui.control.Label
        X_minEditField          matlab.ui.control.NumericEditField
        X_maxEditFieldLabel     matlab.ui.control.Label
        X_maxEditField          matlab.ui.control.NumericEditField
        Y_minEditFieldLabel     matlab.ui.control.Label
        Y_minEditField          matlab.ui.control.NumericEditField
        Y_maxEditFieldLabel     matlab.ui.control.Label
        Y_maxEditField          matlab.ui.control.NumericEditField
        Psi_minEditFieldLabel   matlab.ui.control.Label
        Psi_minEditField        matlab.ui.control.NumericEditField
        Psi_maxEditFieldLabel   matlab.ui.control.Label
        Psi_maxEditField        matlab.ui.control.NumericEditField
        B_minEditFieldLabel     matlab.ui.control.Label
        B_minEditField          matlab.ui.control.NumericEditField
        B_maxEditFieldLabel     matlab.ui.control.Label
        B_maxEditField          matlab.ui.control.NumericEditField
        r_minEditFieldLabel     matlab.ui.control.Label
        r_minEditField          matlab.ui.control.NumericEditField
        r_maxEditFieldLabel     matlab.ui.control.Label
        r_maxEditField          matlab.ui.control.NumericEditField
        INPUTSTab               matlab.ui.container.Tab
        InputsPanel             matlab.ui.container.Panel
        minINPUTEditFieldLabel  matlab.ui.control.Label
        minINPUTEditField       matlab.ui.control.NumericEditField
        maxINPUTEditFieldLabel  matlab.ui.control.Label
        maxINPUTEditField       matlab.ui.control.NumericEditField
        dINPUTEditFieldLabel    matlab.ui.control.Label
        dINPUTEditField         matlab.ui.control.NumericEditField
        settingsPanel           matlab.ui.container.Panel
        mEditFieldLabel         matlab.ui.control.Label
        mEditField              matlab.ui.control.NumericEditField
        LfEditFieldLabel        matlab.ui.control.Label
        LfEditField             matlab.ui.control.NumericEditField
        LrEditFieldLabel        matlab.ui.control.Label
        LrEditField             matlab.ui.control.NumericEditField
        JEditFieldLabel         matlab.ui.control.Label
        JEditField              matlab.ui.control.NumericEditField
        CfEditFieldLabel        matlab.ui.control.Label
        CfEditField             matlab.ui.control.NumericEditField
        CrEditFieldLabel        matlab.ui.control.Label
        CrEditField             matlab.ui.control.NumericEditField
        VEditFieldLabel         matlab.ui.control.Label
        VEditField              matlab.ui.control.NumericEditField
        sicurezzaSliderLabel    matlab.ui.control.Label
        sicurezzaSlider         matlab.ui.control.Slider
        stopperSliderLabel      matlab.ui.control.Label
        stopperSlider           matlab.ui.control.Slider
        DIMENSIONSTab           matlab.ui.container.Tab
        Label                   matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        Label_2                 matlab.ui.control.Label
        DropDown_2              matlab.ui.control.DropDown
        Label_3                 matlab.ui.control.Label
        DropDown_3              matlab.ui.control.DropDown
        Label_4                 matlab.ui.control.Label
        DropDown_4              matlab.ui.control.DropDown
        Label_5                 matlab.ui.control.Label
        DropDown_5              matlab.ui.control.DropDown
        dim4TextArea            matlab.ui.control.TextArea
        dim5TextArea            matlab.ui.control.TextArea
        Slider4                 matlab.ui.control.Slider
        Slider5                 matlab.ui.control.Slider
        val4TextArea            matlab.ui.control.TextArea
        val5TextArea            matlab.ui.control.TextArea
        stateLampLabel          matlab.ui.control.Label
        stateLamp               matlab.ui.control.Lamp
        PLAYButton              matlab.ui.control.Button
        Switch                  matlab.ui.control.Switch
        ACVPROJECTLabel         matlab.ui.control.Label
        messaggiTextArea        matlab.ui.control.TextArea
        Panel                   matlab.ui.container.Panel
        DESIGNOBSTACLEButton    matlab.ui.control.Button
        LOADOBSTACLEButton      matlab.ui.control.Button
        LOADICSButton           matlab.ui.control.Button
        CheckBox                matlab.ui.control.CheckBox
        FATOBSTACLEButton       matlab.ui.control.Button
        CheckBox_2              matlab.ui.control.CheckBox
        Button                  matlab.ui.control.Button
        OBSButton               matlab.ui.control.Button
        TERMINAPanel            matlab.ui.container.Panel
        XYCheckBox              matlab.ui.control.CheckBox
        AreaCheckBox            matlab.ui.control.CheckBox
        XbCheckBox              matlab.ui.control.CheckBox
        NpuntiCheckBox          matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        dX
        dY
        dPsi
        dB
        dr
        ris1
        ris2
        ris3
        ris4
        ris5
        dim1
        dim2
        dim3
        dim4
        dim5
        minX
        maxX
        minY
        maxY
        minPsi
        maxPsi
        minB
        maxB
        minr
        maxr
        min1
        max1
        min2
        max2
        min3
        max3
        min4
        max4
        min5
        max5
        fix4
        fix5
        Rcar
        K
        load_switch
        ICS
        TMP
        interruttore
        h
        posX
        posY
        posPsi
        posB        
        posr
        text
        ICSloaded
        FATOBSon
        minINPUT
        maxINPUT
        FATOBS
    end
    
    
    methods (Access = public)
        
        %prende in input messaggio e lo pubblica su Area
        function stampa(app,value)
            app.messaggiTextArea.Value=value;
            drawnow;
        end
        
        %prendo in input K e store+salva
        function transfer(app,K,check,dest)
            app.K=K;
            if check
                save(dest,'K','-v7.3');
            end
            drawnow;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.dPsi=app.dXEditField_2.Value;
            app.dX=app.dXEditField.Value;
            app.dY=app.dYEditField.Value;
            app.dPsi=app.dPsiEditField.Value;
            app.dB=app.dBEditField.Value;
            app.dr=app.drEditField.Value;
            
            app.ris1=app.dX;
            app.ris2=app.dY;
            app.ris3=app.dPsi;
            app.ris4=app.dB;
            app.ris5=app.dr;
            
            app.minX=app.X_minEditField.Value;
            app.maxX=app.X_maxEditField.Value;
            app.minY=app.Y_minEditField.Value;
            app.maxY=app.Y_maxEditField.Value;
            app.minPsi=app.Psi_minEditField.Value;
            app.maxPsi=app.Psi_maxEditField.Value;
            app.minB=app.B_minEditField.Value;
            app.maxB=app.B_maxEditField.Value;
            app.minr=app.r_minEditField.Value;
            app.maxr=app.r_maxEditField.Value;
            
            app.min1=app.minX;
            app.max1=app.maxX;
            app.min2=app.minY;
            app.max2=app.maxY;
            app.min3=app.minPsi;
            app.max3=app.maxPsi;
            app.min4=app.minB;
            app.max4=app.maxB;
            app.min5=app.minr;
            app.max5=app.maxr;
            
            app.fix4=(app.min4+app.max4)/2;
            app.fix5=(app.min5+app.max5)/2;
            
            app.dim1=app.DropDown.Value;
            app.dim2=app.DropDown_2.Value;
            app.dim3=app.DropDown_3.Value;
            app.dim4=app.DropDown_4.Value;
            app.dim5=app.DropDown_5.Value;
            
            app.dim4TextArea.Value=app.dim4;
            app.dim5TextArea.Value=app.dim5;
            app.Slider4.Limits=[app.min4,app.max4];
            app.Slider5.Limits=[app.min5,app.max5];
            
            app.load_switch=0;            
            app.Rcar=app.RcarEditField.Value;
            app.K=load('K.mat').K;
            app.ICS=load('ICS.mat').ICS;
            app.ICS=double.empty;
            app.TMP=app.ICS;
            app.interruttore=0;
            app.stateLamp.Color='red';
            app.ICSloaded=0;
            
            app.posX=1;
            app.posY=2;
            app.posPsi=3;
            app.posB=4;
            app.posr=5;
            app.FATOBSon=0;
            app.FATOBS=app.K;
            
            app.minINPUT=app.minINPUTEditField.Value;
            app.maxINPUT=app.maxINPUTEditField.Value;
            
            app.sicurezzaSlider.Limits=[0,0.49];
            app.sicurezzaSlider.Value=0;
            
            app.stopperSlider.Limits=[0,0.05];
            app.stopperSlider.Value=0.01;
            
            app.XYCheckBox.Value=1;
            app.AreaCheckBox.Value=0;
            app.XbCheckBox.Value=0;
            app.NpuntiCheckBox.Value=0;
            
            drawnow
        end

        % Value changed function: dXEditField_2
        function dXEditField_2ValueChanged(app, event)
            value = app.dXEditField_2.Value;
            app.dPsi=value;
        end

        % Value changed function: dXEditField
        function dXEditFieldValueChanged(app, event)
            value = app.dXEditField.Value;
            app.dX=value;
            %aggiorno le dimensioni numeriche associate a quelle fisiche
            tmp=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            for j=1:5
                if tmp(j)=='X'
                    switch j
                        case 1
                            app.ris1=value;
                        case 2
                            app.ris2=value;
                        case 3
                            app.ris3=value;
                        case 4
                            app.ris4=value;
                        case 5
                            app.ris5=value;
                    end                    
                end
                j=j+1;
            end
        end

        % Value changed function: dYEditField
        function dYEditFieldValueChanged(app, event)
            value = app.dYEditField.Value;
            app.dY=value;
            tmp=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            for j=1:5
                if tmp(j)=='Y'
                    switch j
                        case 1
                            app.ris1=value;
                        case 2
                            app.ris2=value;
                        case 3
                            app.ris3=value;
                        case 4
                            app.ris4=value;
                        case 5
                            app.ris5=value;
                    end                    
                end
                j=j+1;
            end
        end

        % Value changed function: dPsiEditField
        function dPsiEditFieldValueChanged(app, event)
            value = app.dPsiEditField.Value;
            app.dPsi=value;
            tmp=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            for j=1:5
                if tmp(j)=='T'
                    switch j
                        case 1
                            app.ris1=value;
                        case 2
                            app.ris2=value;
                        case 3
                            app.ris3=value;
                        case 4
                            app.ris4=value;
                        case 5
                            app.ris5=value;
                    end                    
                end
                j=j+1;
            end
        end

        % Value changed function: dBEditField
        function dBEditFieldValueChanged(app, event)
            value = app.dBEditField.Value;
            app.dB=value;
            tmp=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            for j=1:5
                if tmp(j)=='V'
                    switch j
                        case 1
                            app.ris1=value;
                        case 2
                            app.ris2=value;
                        case 3
                            app.ris3=value;
                        case 4
                            app.ris4=value;
                        case 5
                            app.ris5=value;
                    end                    
                end
                j=j+1;
            end
        end

        % Value changed function: drEditField
        function drEditFieldValueChanged(app, event)
            value = app.drEditField.Value;
            app.dr=value;
            tmp=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            for j=1:5
                if tmp(j)=='G'
                    switch j
                        case 1
                            app.ris1=value;
                        case 2
                            app.ris2=value;
                        case 3
                            app.ris3=value;
                        case 4
                            app.ris4=value;
                        case 5
                            app.ris5=value;
                    end                    
                end
                j=j+1;
            end
        end

        % Value changed function: RcarEditField
        function RcarEditFieldValueChanged(app, event)
            value = app.RcarEditField.Value;
            app.Rcar=value;
        end

        % Value changed function: X_minEditField
        function X_minEditFieldValueChanged(app, event)
            value = app.X_minEditField.Value;
            app.minX=value;
            tmp=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            for j=1:5
                if tmp(j)=='X'
                    switch j
                        case 1
                            app.min1=value;
                        case 2
                            app.min2=value;
                        case 3
                            app.min3=value;
                        case 4
                            app.min4=value;
                        case 5
                            app.min5=value;
                    end                    
                end
                j=j+1;
            end
        end

        % Value changed function: X_maxEditField
        function X_maxEditFieldValueChanged(app, event)
            value = app.X_maxEditField.Value;
            app.maxX=value;
            tmp=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            for j=1:5
                if tmp(j)=='X'
                    switch j
                        case 1
                            app.max1=value;
                        case 2
                            app.max2=value;
                        case 3
                            app.max3=value;
                        case 4
                            app.max4=value;
                        case 5
                            app.max5=value;
                    end                    
                end
                j=j+1;
            end
        end

        % Value changed function: Y_minEditField
        function Y_minEditFieldValueChanged(app, event)
            value = app.Y_minEditField.Value;
            app.minY=value;
            tmp=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            for j=1:5
                if tmp(j)=='Y'
                    switch j
                        case 1
                            app.min1=value;
                        case 2
                            app.min2=value;
                        case 3
                            app.min3=value;
                        case 4
                            app.min4=value;
                        case 5
                            app.min5=value;
                    end                    
                end
                j=j+1;
            end
        end

        % Value changed function: Y_maxEditField
        function Y_maxEditFieldValueChanged(app, event)
            value = app.Y_maxEditField.Value;
            app.maxY=value;
            tmp=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            for j=1:5
                if tmp(j)=='Y'
                    switch j
                        case 1
                            app.max1=value;
                        case 2
                            app.max2=value;
                        case 3
                            app.max3=value;
                        case 4
                            app.max4=value;
                        case 5
                            app.max5=value;
                    end                    
                end
                j=j+1;
            end
        end

        % Value changed function: Psi_minEditField
        function Psi_minEditFieldValueChanged(app, event)
            value = app.Psi_minEditField.Value;
            app.minPsi=value;
            tmp=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            for j=1:5
                if tmp(j)=='Psi'
                    switch j
                        case 1
                            app.min1=value;
                        case 2
                            app.min2=value;
                        case 3
                            app.min3=value;
                        case 4
                            app.min4=value;
                        case 5
                            app.min5=value;
                    end                    
                end
                j=j+1;
            end
        end

        % Value changed function: Psi_maxEditField
        function Psi_maxEditFieldValueChanged(app, event)
            value = app.Psi_maxEditField.Value;
            app.maxPsi=value;
            tmp=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            for j=1:5
                if tmp(j)=='Psi'
                    switch j
                        case 1
                            app.max1=value;
                        case 2
                            app.max2=value;
                        case 3
                            app.max3=value;
                        case 4
                            app.max4=value;
                        case 5
                            app.max5=value;
                    end                    
                end
                j=j+1;
            end
        end

        % Value changed function: B_minEditField
        function B_minEditFieldValueChanged(app, event)
            value = app.B_minEditField.Value;
            app.minB=value;
            tmp=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            for j=1:5
                if tmp(j)=='B'
                    switch j
                        case 1
                            app.min1=value;
                        case 2
                            app.min2=value;
                        case 3
                            app.min3=value;
                        case 4
                            app.min4=value;
                        case 5
                            app.min5=value;
                    end                    
                end
                j=j+1;
            end
        end

        % Value changed function: B_maxEditField
        function B_maxEditFieldValueChanged(app, event)
            value = app.B_maxEditField.Value;
            app.maxB=value;
            tmp=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            for j=1:5
                if tmp(j)=='B'
                    switch j
                        case 1
                            app.max1=value;
                        case 2
                            app.max2=value;
                        case 3
                            app.max3=value;
                        case 4
                            app.max4=value;
                        case 5
                            app.max5=value;
                    end                    
                end
                j=j+1;
            end
        end

        % Value changed function: r_minEditField
        function r_minEditFieldValueChanged(app, event)
            value = app.r_minEditField.Value;
            app.minr=value;
            tmp=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            for j=1:5
                if tmp(j)=='r'
                    switch j
                        case 1
                            app.min1=value;
                        case 2
                            app.min2=value;
                        case 3
                            app.min3=value;
                        case 4
                            app.min4=value;
                        case 5
                            app.min5=value;
                    end                    
                end
                j=j+1;
            end
        end

        % Value changed function: r_maxEditField
        function r_maxEditFieldValueChanged(app, event)
            value = app.r_maxEditField.Value;
            app.maxr=value;
            tmp=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            for j=1:5
                if tmp(j)=='r'
                    switch j
                        case 1
                            app.max1=value;
                        case 2
                            app.max2=value;
                        case 3
                            app.max3=value;
                        case 4
                            app.max4=value;
                        case 5
                            app.max5=value;
                    end                    
                end
                j=j+1;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case 'X'
                    app.dim1='X';
                    app.min1=app.minX;
                    app.max1=app.maxX;
                    app.ris1=app.dX;
                    ind=app.posX;
                case 'Y'
                    app.dim1='Y';
                    app.min1=app.minY;
                    app.max1=app.maxY;
                    app.ris1=app.dY;
                    ind=app.posY;
                case 'Psi'
                    app.dim1='Psi';
                    app.min1=app.minPsi;
                    app.max1=app.maxPsi;
                    app.ris1=app.dPsi;
                    ind=app.posPsi;
                case 'B'
                    app.dim1='B';
                    app.min1=app.minB;
                    app.max1=app.maxB;
                    app.ris1=app.dB;
                    ind=app.posB;
                case 'r'
                    app.dim1='r';
                    app.min1=app.minr;
                    app.max1=app.maxr;
                    app.ris1=app.dr;
                    ind=app.posr;
            end
            
            if app.interruttore
               app.TMP(:,1)=app.ICS(:,ind);
               app.h=plottiamo(app.TMP,app.fix4,app.fix5,app.dim1,app.dim2,app.dim3,app.min1,app.max1,app.min2,app.max2,app.min3,app.max3,app.h,app.Switch.Value,[app.ris1,app.ris2,app.ris3,app.ris4,app.ris5]);
            end            
            drawnow            
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            switch value
                case 'X'
                    app.dim2='X';
                    app.min2=app.minX;
                    app.max2=app.maxX;
                    app.ris2=app.dX;
                    ind=app.posX;
                case 'Y'
                    app.dim2='Y';
                    app.min2=app.minY;
                    app.max2=app.maxY;
                    app.ris2=app.dY;
                    ind=app.posY;
                case 'Psi'
                    app.dim2='Psi';
                    app.min2=app.minPsi;
                    app.max2=app.maxPsi;
                    app.ris2=app.dPsi;
                    ind=app.posPsi;
                case 'B'
                    app.dim2='B';
                    app.min2=app.minB;
                    app.max2=app.maxB;
                    app.ris2=app.dB;
                    ind=app.posB;
                case 'r'
                    app.dim2='r';
                    app.min2=app.minr;
                    app.max2=app.maxr;
                    app.ris2=app.dr;
                    ind=app.posr;
            end
            
            if app.interruttore
                app.TMP(:,2)=app.ICS(:,ind);
                app.h=plottiamo(app.TMP,app.fix4,app.fix5,app.dim1,app.dim2,app.dim3,app.min1,app.max1,app.min2,app.max2,app.min3,app.max3,app.h,app.Switch.Value,[app.ris1,app.ris2,app.ris3,app.ris4,app.ris5]);
            end
            drawnow
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            switch value
                case 'X'
                    app.dim3='X';
                    app.min3=app.minX;
                    app.max3=app.maxX;
                    app.ris3=app.dX;
                    ind=app.posX;
                case 'Y'
                    app.dim3='Y';
                    app.min3=app.minY;
                    app.max3=app.maxY;
                    app.ris3=app.dY;
                    ind=app.posY;
                case 'Psi'
                    app.dim3='Psi';
                    app.min3=app.minPsi;
                    app.max3=app.maxPsi;
                    app.ris3=app.dPsi;
                    ind=app.posPsi;
                case 'B'
                    app.dim3='B';
                    app.min3=app.minB;
                    app.max3=app.maxB;
                    app.ris3=app.dB;
                    ind=app.posB;
                case 'r'
                    app.dim3='r';
                    app.min3=app.minr;
                    app.max3=app.maxr;
                    app.ris3=app.dr;
                    ind=app.posr;
            end
            if app.interruttore
               app.TMP(:,3)=app.ICS(:,ind);
               app.h=plottiamo(app.TMP,app.fix4,app.fix5,app.dim1,app.dim2,app.dim3,app.min1,app.max1,app.min2,app.max2,app.min3,app.max3,app.h,app.Switch.Value,[app.ris1,app.ris2,app.ris3,app.ris4,app.ris5]);
            end
            drawnow
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            value = app.DropDown_4.Value;
            switch value
                case 'X'
                    app.dim4='X';
                    app.min4=app.minX;
                    app.max4=app.maxX;
                    app.ris4=app.dX;
                    ind=app.posX;
                case 'Y'
                    app.dim4='Y';
                    app.min4=app.minY;
                    app.max4=app.maxY;
                    app.ris4=app.dY;
                    ind=app.posY;
                case 'Psi'
                    app.dim4='Psi';
                    app.min4=app.minPsi;
                    app.max4=app.maxPsi;
                    app.ris4=app.dPsi;
                    ind=app.posPsi;
                case 'B'
                    app.dim4='B';
                    app.min4=app.minB;
                    app.max4=app.maxB;
                    app.ris4=app.dB;
                    ind=app.posB;
                case 'r'
                    app.dim4='r';
                    app.min4=app.minr;
                    app.max4=app.maxr;
                    app.ris4=app.dr;
                    ind=app.posr;
            end
 
                app.dim4TextArea.Value=app.dim4;
                app.Slider4.Limits=[app.min4,app.max4];
                app.fix4=round((app.min4+app.max4)/2/app.ris4)*app.ris4;
                app.Slider4.Value=app.fix4;  
                if app.interruttore
                   app.TMP(:,4)=app.ICS(:,ind);
                   app.h=plottiamo(app.TMP,app.fix4,app.fix5,app.dim1,app.dim2,app.dim3,app.min1,app.max1,app.min2,app.max2,app.min3,app.max3,app.h,app.Switch.Value,[app.ris1,app.ris2,app.ris3,app.ris4,app.ris5]);
                end
                drawnow
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            value = app.DropDown_5.Value;
            switch value
                case 'X'
                    app.dim5='X';
                    app.min5=app.minX;
                    app.max5=app.maxX;
                    app.ris5=app.dX;
                    ind=app.posX;
                case 'Y'
                    app.dim5='Y';
                    app.min5=app.minY;
                    app.max5=app.maxY;
                    app.ris5=app.dY;
                    ind=app.posY;
                case 'Psi'
                    app.dim5='Psi';
                    app.min5=app.minPsi;
                    app.max5=app.maxPsi;
                    app.ris5=app.dPsi;
                    ind=app.posPsi;
                case 'B'
                    app.dim5='B';
                    app.min5=app.minB;
                    app.max5=app.maxB;
                    app.ris5=app.dB;
                    ind=app.posB;
                case 'r'
                    app.dim5='r';
                    app.min5=app.minr;
                    app.max5=app.maxr;
                    app.ris5=app.dr;
                    ind=app.posr;                    
            end              
                app.dim5TextArea.Value=app.dim5;
                app.Slider5.Limits=[app.min5,app.max5];
                app.fix5=round((app.min5+app.max5)/2/app.ris5)*app.ris5;
                app.Slider5.Value=app.fix5;
                if app.interruttore
                   app.TMP(:,5)=app.ICS(:,ind);
                   app.h=plottiamo(app.TMP,app.fix4,app.fix5,app.dim1,app.dim2,app.dim3,app.min1,app.max1,app.min2,app.max2,app.min3,app.max3,app.h,app.Switch.Value,[app.ris1,app.ris2,app.ris3,app.ris4,app.ris5]);
                end
                drawnow
        end

        % Value changed function: dim4TextArea
        function dim4TextAreaValueChanged(app, event)
                        
        end

        % Value changed function: dim5TextArea
        function dim5TextAreaValueChanged(app, event)
                        
        end

        % Value changed function: val4TextArea
        function val4TextAreaValueChanged(app, event)
                       
        end

        % Value changed function: val5TextArea
        function val5TextAreaValueChanged(app, event)
                        
        end

        % Value changing function: Slider4
        function Slider4ValueChanging(app, event)
            value = event.Value;
            app.fix4=round(value/app.ris4)*app.ris4;
            app.val4TextArea.Value=num2str(app.fix4);
            if app.interruttore
               app.h=plottiamo(app.TMP,app.fix4,app.fix5,app.dim1,app.dim2,app.dim3,app.min1,app.max1,app.min2,app.max2,app.min3,app.max3,app.h,app.Switch.Value,[app.ris1,app.ris2,app.ris3,app.ris4,app.ris5]);
            end
            drawnow            
        end

        % Value changing function: Slider5
        function Slider5ValueChanging(app, event)
            value = event.Value;
            app.fix5=round(value/app.ris5)*app.ris5;
            app.val5TextArea.Value=num2str(app.fix5);
            if app.interruttore
               app.h=plottiamo(app.TMP,app.fix4,app.fix5,app.dim1,app.dim2,app.dim3,app.min1,app.max1,app.min2,app.max2,app.min3,app.max3,app.h,app.Switch.Value,[app.ris1,app.ris2,app.ris3,app.ris4,app.ris5]);
            end
            drawnow            
        end

        % Button pushed function: LOADOBSTACLEButton
        function LOADOBSTACLEButtonPushed(app, event)
            app.ICSloaded=0;
            [FileName,FilePath]= uigetfile('**');
            if FilePath~=0
               app.K=load(string(FilePath)+string(FileName)).K;
               app.stampa('5D obstacle is ready: press PLAY');
            end            
            app.stateLamp.Color='red';
            figure(app.ACVproject);
            drawnow
        end

        % Button pushed function: DESIGNOBSTACLEButton
        function DESIGNOBSTACLEButtonPushed(app, event)
            app.ICSloaded=0;
            SavePath='K';
            if app.CheckBox.Value
               SavePath=string(uigetdir())+'\K';
            end
            DISEGNA([app.ris1,app.ris2,app.ris3,app.ris4,app.ris5],[app.min1,app.max1,app.min2,app.max2,app.min3,app.max3,app.min4,app.max4,app.min5,app.max5],app,app.CheckBox.Value,SavePath);
            app.stateLamp.Color='red';
            figure(app.ACVproject);
            drawnow
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            app.interruttore=1;
            if ~app.ICSloaded
                
                INPUT=[app.minINPUT,app.maxINPUT];
                RIS=[app.ris1,app.ris2,app.ris3,app.ris4,app.ris5,app.dXEditField_2.Value,app.dINPUTEditField.Value];
                BOUNDS=[-app.min1,app.max1,-app.min2,app.max2,-app.min3,app.max3,-app.min4,app.max4,-app.min5,app.max5];
                settings=[app.mEditField.Value,app.LfEditField.Value,app.LrEditField.Value,app.JEditField.Value,app.CfEditField.Value,app.CrEditField.Value,app.VEditField.Value];
                USCITA=[app.AreaCheckBox.Value,app.XYCheckBox.Value,app.XbCheckBox.Value,app.NpuntiCheckBox.Value];
                
                [app.ICS,app.h,app.FATOBS]=MAIN(INPUT,RIS,BOUNDS,settings,app.K,app.Rcar,app,app.sicurezzaSlider.Value,app.stopperSlider.Value,USCITA);
                if app.CheckBox_2.Value
                    ICS=app.ICS;
                    save('ICS','ICS','-v7.3');
                    app.stampa(string('ICS saved'));
                end
            end
            app.TMP=app.ICS;
            app.h=plottiamo(app.TMP,app.fix4,app.fix5,app.dim1,app.dim2,app.dim3,app.min1,app.max1,app.min2,app.max2,app.min3,app.max3,app.h,app.Switch.Value,[app.ris1,app.ris2,app.ris3,app.ris4,app.ris5]);
            app.stateLamp.Color='green';
            drawnow
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if app.interruttore
               app.h=plottiamo(app.TMP,app.fix4,app.fix5,app.dim1,app.dim2,app.dim3,app.min1,app.max1,app.min2,app.max2,app.min3,app.max3,app.h,value,[app.ris1,app.ris2,app.ris3,app.ris4,app.ris5]);
            end
            drawnow
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
        end

        % Value changed function: messaggiTextArea
        function messaggiTextAreaValueChanged(app, event)
            value = app.messaggiTextArea.Value;
        end

        % Button pushed function: LOADICSButton
        function LOADICSButtonPushed(app, event)
            [FileName,FilePath]= uigetfile('**');
            if FilePath~=0
               app.ICS=load(string(FilePath)+string(FileName)).ICS;
               app.stampa('ICS loaded: press PLAY to visualize');
               app.ICSloaded=1;
            end
            figure(app.ACVproject);
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
        end

        % Button pushed function: FATOBSTACLEButton
        function FATOBSTACLEButtonPushed(app, event)
            if app.Rcar~=0
                app.FATOBSon=1;
                RIS=[app.dX,app.dY,app.dPsi,app.dB,app.dr];
                BOUNDS=[app.minX,app.maxX,app.minY,app.maxY,app.minPsi,app.maxPsi,app.minB,app.maxB,app.minr,app.maxr];
                FATOBS=espandi(app.K,app.Rcar,RIS,BOUNDS);
                %serve per prendere sezione ostacolo da plottare
                dim={app.dim1,app.dim2,app.dim3,app.dim4,app.dim5};
                ind=zeros(1,5);
                for j=1:5
                    switch dim{j}
                        case 'X'
                            ind(j)=app.posX;
                        case 'Y'
                            ind(j)=app.posY;
                        case 'Psi'
                            ind(j)=app.posPsi;
                        case 'B'
                            ind(j)=app.posB;
                        case 'r'
                            ind(j)=app.posr;
                    end
                end
                %prendo sezione di FATOBS
                app.FATOBS=[FATOBS(:,ind(1)),FATOBS(:,ind(2)),FATOBS(:,ind(3)),FATOBS(:,ind(4)),FATOBS(:,ind(5))];
                sz=size(FATOBS);
                FATOBS=FATOBS(FATOBS(:,4)==repmat(app.fix4,sz(1),1) & FATOBS(:,5)==repmat(app.fix5,sz(1),1),:);
                %prendo sezione K
                K=[app.K(:,ind(1)),app.K(:,ind(2)),app.K(:,ind(3)),app.K(:,ind(4)),app.K(:,ind(5))];
                szK=size(K);
                K=K(K(:,4)==repmat(app.fix4,szK(1),1) & K(:,5)==repmat(app.fix5,szK(1),1),:);
                               
                k=figure;

                if app.Switch.Value=='2D'
                    k=scatter(FATOBS(:,1),FATOBS(:,2),'s','fill','MarkerEdgeColor','k');grid on;axis([app.min1,app.max1,app.min2,app.max2]);xlabel(app.dim1);ylabel(app.dim2);title(string(app.dim1)+'-'+string(app.dim2));
                    hold on;
                    scatter(K(:,1),K(:,2),'s','fill','MarkerEdgeColor','r');
                else
                    k=scatter3(FATOBS(:,1),FATOBS(:,2),FATOBS(:,3),'s','fill','MarkerEdgeColor','k');grid on;axis([app.min1,app.max1,app.min2,app.max2,app.min3,app.max3]);xlabel(app.dim1);ylabel(app.dim2);zlabel(app.dim3);title(string(app.dim1)+'-'+string(app.dim2)+'-'+string(app.dim3));
                    hold on;
                    scatter3(K(:,1),K(:,2),K(:,3),'s','fill','MarkerEdgeColor','r');
                end
                app.stampa(string('Enlarged obstacle for Rcar=')+num2str(app.Rcar));
            else
                app.stampa('Rcar=0: enlarged obstacle=obstacle!');
            end
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            dim=[app.dim1,app.dim2,app.dim3,app.dim4,app.dim5];
            ind=zeros(1,5);
            for j=1:5
                switch dim(j)
                    case 'X'
                        ind(j)=app.posX;
                    case 'Y'
                        ind(j)=app.posY;
                    case 'Psi'
                        ind(j)=app.posPsi;
                    case 'B'
                        ind(j)=app.posB;
                    case 'r'
                        ind(j)=app.posr;
                end
            end
            FATOBS=[app.FATOBS(:,ind(1)),app.FATOBS(:,ind(2)),app.FATOBS(:,ind(3)),app.FATOBS(:,ind(4)),app.FATOBS(:,ind(5))];
            sz=size(FATOBS);
            FATOBS=FATOBS(FATOBS(:,4)==repmat(app.fix4,sz(1),1) & FATOBS(:,5)==repmat(app.fix5,sz(1),1),:);
            if app.Switch.Value=='2D'
                hold on;
                add=scatter(FATOBS(:,1),FATOBS(:,2),'s','fill','MarkerEdgeColor','r');
            else
                hold on;
                add=scatter3(FATOBS(:,1),FATOBS(:,2),FATOBS(:,3),'s','fill','MarkerEdgeColor','r');
            end
            hold off
            
        end

        % Value changed function: minINPUTEditField
        function minINPUTEditFieldValueChanged(app, event)
            value = app.minINPUTEditField.Value;
            app.minINPUT=value;
        end

        % Value changed function: maxINPUTEditField
        function maxINPUTEditFieldValueChanged(app, event)
            value = app.maxINPUTEditField.Value;
            app.maxINPUT=value;
        end

        % Value changing function: sicurezzaSlider
        function sicurezzaSliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Button pushed function: OBSButton
        function OBSButtonPushed(app, event)
            dim={app.dim1,app.dim2,app.dim3,app.dim4,app.dim5};
            ind=zeros(1,5);
            for j=1:5
                switch dim{j}
                    case 'X'
                        ind(j)=app.posX;
                    case 'Y'
                        ind(j)=app.posY;
                    case 'Psi'
                        ind(j)=app.posPsi;
                    case 'B'
                        ind(j)=app.posB;
                    case 'r'
                        ind(j)=app.posr;
                end
            end
            OBS=[app.K(:,ind(1)),app.K(:,ind(2)),app.K(:,ind(3)),app.K(:,ind(4)),app.K(:,ind(5))];
            sz=size(OBS);
            OBS=OBS(OBS(:,4)==repmat(app.fix4,sz(1),1) & OBS(:,5)==repmat(app.fix5,sz(1),1),:);
            if app.Switch.Value=='2D'
                hold on;
                add=scatter(OBS(:,1),OBS(:,2),'s','fill','MarkerFaceColor','r','MarkerEdgeColor','k');
            else
                hold on;
                add=scatter3(OBS(:,1),OBS(:,2),OBS(:,3),'s','fill','MarkerFaceColor','r','MarkerEdgeColor','k');
            end
            hold off
        end

        % Value changing function: stopperSlider
        function stopperSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: AreaCheckBox
        function AreaCheckBoxValueChanged(app, event)
            value = app.AreaCheckBox.Value;
            
        end

        % Value changed function: XYCheckBox
        function XYCheckBoxValueChanged(app, event)
            value = app.XYCheckBox.Value;
            
        end

        % Value changed function: XbCheckBox
        function XbCheckBoxValueChanged(app, event)
            value = app.XbCheckBox.Value;
            
        end

        % Value changed function: NpuntiCheckBox
        function NpuntiCheckBoxValueChanged(app, event)
            value = app.NpuntiCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ACVproject = uifigure('Visible', 'off');
            app.ACVproject.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.ACVproject);
            app.RESOLUTIONSTab = uitab(app.TabGroup);
            app.RESOLUTIONSTab.Title = 'RESOLUTIONS';
            app.ResolutionsPanel = uipanel(app.RESOLUTIONSTab);
            app.ResolutionsPanel.Title = 'Resolutions';
            app.dXEditField_2Label = uilabel(app.ResolutionsPanel);
            app.dXEditField_2Label.Text = 'dX';
            app.dXEditField_2 = uieditfield(app.ResolutionsPanel, 'numeric');
            app.dXEditField_2.ValueChangedFcn = createCallbackFcn(app, @dXEditField_2ValueChanged, true);
            app.dXEditField_2.Value = 0.1;
            app.dXEditFieldLabel = uilabel(app.ResolutionsPanel);
            app.dXEditFieldLabel.Text = 'dX';
            app.dXEditField = uieditfield(app.ResolutionsPanel, 'numeric');
            app.dXEditField.ValueChangedFcn = createCallbackFcn(app, @dXEditFieldValueChanged, true);
            app.dXEditField.Value = 1;
            app.dYEditFieldLabel = uilabel(app.ResolutionsPanel);
            app.dYEditFieldLabel.Text = 'dY';
            app.dYEditField = uieditfield(app.ResolutionsPanel, 'numeric');
            app.dYEditField.ValueChangedFcn = createCallbackFcn(app, @dYEditFieldValueChanged, true);
            app.dYEditField.Value = 1;
            app.dPsiEditFieldLabel = uilabel(app.ResolutionsPanel);
            app.dPsiEditFieldLabel.Text = 'dPsi';
            app.dPsiEditField = uieditfield(app.ResolutionsPanel, 'numeric');
            app.dPsiEditField.ValueChangedFcn = createCallbackFcn(app, @dPsiEditFieldValueChanged, true);
            app.dPsiEditField.Value = 0.19625;
            app.dBEditFieldLabel = uilabel(app.ResolutionsPanel);
            app.dBEditFieldLabel.Text = 'dB';
            app.dBEditField = uieditfield(app.ResolutionsPanel, 'numeric');
            app.dBEditField.ValueChangedFcn = createCallbackFcn(app, @dBEditFieldValueChanged, true);
            app.dBEditField.Value = 0.02453125;
            app.drEditFieldLabel = uilabel(app.ResolutionsPanel);
            app.drEditFieldLabel.Text = 'dr';
            app.drEditField = uieditfield(app.ResolutionsPanel, 'numeric');
            app.drEditField.ValueChangedFcn = createCallbackFcn(app, @drEditFieldValueChanged, true);
            app.drEditField.Value = 0.0490625;
            app.RcarEditFieldLabel = uilabel(app.ResolutionsPanel);
            app.RcarEditFieldLabel.Text = 'Rcar';
            app.RcarEditField = uieditfield(app.ResolutionsPanel, 'numeric');
            app.RcarEditField.ValueChangedFcn = createCallbackFcn(app, @RcarEditFieldValueChanged, true);
            app.BoundsPanel = uipanel(app.RESOLUTIONSTab);
            app.BoundsPanel.Title = 'Bounds';
            app.X_minEditFieldLabel = uilabel(app.BoundsPanel);
            app.X_minEditFieldLabel.Text = 'X_min';
            app.X_minEditField = uieditfield(app.BoundsPanel, 'numeric');
            app.X_minEditField.ValueChangedFcn = createCallbackFcn(app, @X_minEditFieldValueChanged, true);
            app.X_minEditField.Value = -30;
            app.X_maxEditFieldLabel = uilabel(app.BoundsPanel);
            app.X_maxEditFieldLabel.Text = 'X_max';
            app.X_maxEditField = uieditfield(app.BoundsPanel, 'numeric');
            app.X_maxEditField.ValueChangedFcn = createCallbackFcn(app, @X_maxEditFieldValueChanged, true);
            app.X_maxEditField.Value = 30;
            app.Y_minEditFieldLabel = uilabel(app.BoundsPanel);
            app.Y_minEditFieldLabel.Text = 'Y_min';
            app.Y_minEditField = uieditfield(app.BoundsPanel, 'numeric');
            app.Y_minEditField.ValueChangedFcn = createCallbackFcn(app, @Y_minEditFieldValueChanged, true);
            app.Y_minEditField.Value = -30;
            app.Y_maxEditFieldLabel = uilabel(app.BoundsPanel);
            app.Y_maxEditFieldLabel.Text = 'Y_max';
            app.Y_maxEditField = uieditfield(app.BoundsPanel, 'numeric');
            app.Y_maxEditField.ValueChangedFcn = createCallbackFcn(app, @Y_maxEditFieldValueChanged, true);
            app.Y_maxEditField.Value = 30;
            app.Psi_minEditFieldLabel = uilabel(app.BoundsPanel);
            app.Psi_minEditFieldLabel.Text = 'Psi_min';
            app.Psi_minEditField = uieditfield(app.BoundsPanel, 'numeric');
            app.Psi_minEditField.ValueChangedFcn = createCallbackFcn(app, @Psi_minEditFieldValueChanged, true);
            app.Psi_maxEditFieldLabel = uilabel(app.BoundsPanel);
            app.Psi_maxEditFieldLabel.Text = 'Psi_max';
            app.Psi_maxEditField = uieditfield(app.BoundsPanel, 'numeric');
            app.Psi_maxEditField.ValueChangedFcn = createCallbackFcn(app, @Psi_maxEditFieldValueChanged, true);
            app.Psi_maxEditField.Value = 6.28;
            app.B_minEditFieldLabel = uilabel(app.BoundsPanel);
            app.B_minEditFieldLabel.Text = 'B_min';
            app.B_minEditField = uieditfield(app.BoundsPanel, 'numeric');
            app.B_minEditField.ValueChangedFcn = createCallbackFcn(app, @B_minEditFieldValueChanged, true);
            app.B_minEditField.Value = -0.098125;
            app.B_maxEditFieldLabel = uilabel(app.BoundsPanel);
            app.B_maxEditFieldLabel.Text = 'B_max';
            app.B_maxEditField = uieditfield(app.BoundsPanel, 'numeric');
            app.B_maxEditField.ValueChangedFcn = createCallbackFcn(app, @B_maxEditFieldValueChanged, true);
            app.B_maxEditField.Value = 0.098125;
            app.r_minEditFieldLabel = uilabel(app.BoundsPanel);
            app.r_minEditFieldLabel.Text = 'r_min';
            app.r_minEditField = uieditfield(app.BoundsPanel, 'numeric');
            app.r_minEditField.ValueChangedFcn = createCallbackFcn(app, @r_minEditFieldValueChanged, true);
            app.r_minEditField.Value = -0.3925;
            app.r_maxEditFieldLabel = uilabel(app.BoundsPanel);
            app.r_maxEditFieldLabel.Text = 'r_max';
            app.r_maxEditField = uieditfield(app.BoundsPanel, 'numeric');
            app.r_maxEditField.ValueChangedFcn = createCallbackFcn(app, @r_maxEditFieldValueChanged, true);
            app.r_maxEditField.Value = 0.3925;
            app.INPUTSTab = uitab(app.TabGroup);
            app.INPUTSTab.Title = 'INPUTS';
            app.InputsPanel = uipanel(app.INPUTSTab);
            app.InputsPanel.Title = 'Inputs';
            app.minINPUTEditFieldLabel = uilabel(app.InputsPanel);
            app.minINPUTEditFieldLabel.Text = 'minINPUT';
            app.minINPUTEditField = uieditfield(app.InputsPanel, 'numeric');
            app.minINPUTEditField.ValueChangedFcn = createCallbackFcn(app, @minINPUTEditFieldValueChanged, true);
            app.minINPUTEditField.Value = -0.098125;
            app.maxINPUTEditFieldLabel = uilabel(app.InputsPanel);
            app.maxINPUTEditFieldLabel.Text = 'maxINPUT';
            app.maxINPUTEditField = uieditfield(app.InputsPanel, 'numeric');
            app.maxINPUTEditField.ValueChangedFcn = createCallbackFcn(app, @maxINPUTEditFieldValueChanged, true);
            app.maxINPUTEditField.Value = 0.098125;
            app.dINPUTEditFieldLabel = uilabel(app.InputsPanel);
            app.dINPUTEditFieldLabel.Text = 'dINPUT';
            app.dINPUTEditField = uieditfield(app.InputsPanel, 'numeric');
            app.dINPUTEditField.Value = 0.00490625;
            app.settingsPanel = uipanel(app.INPUTSTab);
            app.settingsPanel.Title = 'settings';
            app.mEditFieldLabel = uilabel(app.settingsPanel);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.settingsPanel, 'numeric');
            app.mEditField.Value = 1400;
            app.LfEditFieldLabel = uilabel(app.settingsPanel);
            app.LfEditFieldLabel.Text = 'Lf';
            app.LfEditField = uieditfield(app.settingsPanel, 'numeric');
            app.LfEditField.Value = 1.3;
            app.LrEditFieldLabel = uilabel(app.settingsPanel);
            app.LrEditFieldLabel.Text = 'Lr';
            app.LrEditField = uieditfield(app.settingsPanel, 'numeric');
            app.LrEditField.Value = 1.25;
            app.JEditFieldLabel = uilabel(app.settingsPanel);
            app.JEditFieldLabel.Text = 'J';
            app.JEditField = uieditfield(app.settingsPanel, 'numeric');
            app.JEditField.Value = 2000;
            app.CfEditFieldLabel = uilabel(app.settingsPanel);
            app.CfEditFieldLabel.Text = 'Cf';
            app.CfEditField = uieditfield(app.settingsPanel, 'numeric');
            app.CfEditField.Value = 90000;
            app.CrEditFieldLabel = uilabel(app.settingsPanel);
            app.CrEditFieldLabel.Text = 'Cr';
            app.CrEditField = uieditfield(app.settingsPanel, 'numeric');
            app.CrEditField.Value = 90000;
            app.VEditFieldLabel = uilabel(app.settingsPanel);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.settingsPanel, 'numeric');
            app.VEditField.Value = 30;
            app.sicurezzaSliderLabel = uilabel(app.INPUTSTab);
            app.sicurezzaSliderLabel.Text = 'sicurezza';
            app.sicurezzaSlider = uislider(app.INPUTSTab);
            app.sicurezzaSlider.ValueChangingFcn = createCallbackFcn(app, @sicurezzaSliderValueChanging, true);
            app.stopperSliderLabel = uilabel(app.INPUTSTab);
            app.stopperSliderLabel.Text = 'stopper';
            app.stopperSlider = uislider(app.INPUTSTab);
            app.stopperSlider.ValueChangingFcn = createCallbackFcn(app, @stopperSliderValueChanging, true);
            app.DIMENSIONSTab = uitab(app.TabGroup);
            app.DIMENSIONSTab.Title = 'DIMENSIONS';
            app.Label = uilabel(app.DIMENSIONSTab);
            app.Label.Text = '1';
            app.DropDown = uidropdown(app.DIMENSIONSTab);
            app.DropDown.Items = {'X', 'Y', 'Psi', 'B', 'r'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'X';
            app.Label_2 = uilabel(app.DIMENSIONSTab);
            app.Label_2.Text = '2';
            app.DropDown_2 = uidropdown(app.DIMENSIONSTab);
            app.DropDown_2.Items = {'X', 'Y', 'Psi', 'B', 'r'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'Y';
            app.Label_3 = uilabel(app.DIMENSIONSTab);
            app.Label_3.Text = '3';
            app.DropDown_3 = uidropdown(app.DIMENSIONSTab);
            app.DropDown_3.Items = {'X', 'Y', 'Psi', 'B', 'r'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'Psi';
            app.Label_4 = uilabel(app.DIMENSIONSTab);
            app.Label_4.Text = '4';
            app.DropDown_4 = uidropdown(app.DIMENSIONSTab);
            app.DropDown_4.Items = {'X', 'Y', 'Psi', 'B', 'r'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = 'B';
            app.Label_5 = uilabel(app.DIMENSIONSTab);
            app.Label_5.Text = '5';
            app.DropDown_5 = uidropdown(app.DIMENSIONSTab);
            app.DropDown_5.Items = {'X', 'Y', 'Psi', 'B', 'r'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = 'r';
            app.dim4TextArea = uitextarea(app.DIMENSIONSTab);
            app.dim4TextArea.ValueChangedFcn = createCallbackFcn(app, @dim4TextAreaValueChanged, true);
            app.dim5TextArea = uitextarea(app.DIMENSIONSTab);
            app.dim5TextArea.ValueChangedFcn = createCallbackFcn(app, @dim5TextAreaValueChanged, true);
            app.Slider4 = uislider(app.DIMENSIONSTab);
            app.Slider4.ValueChangingFcn = createCallbackFcn(app, @Slider4ValueChanging, true);
            app.Slider5 = uislider(app.DIMENSIONSTab);
            app.Slider5.ValueChangingFcn = createCallbackFcn(app, @Slider5ValueChanging, true);
            app.val4TextArea = uitextarea(app.DIMENSIONSTab);
            app.val4TextArea.ValueChangedFcn = createCallbackFcn(app, @val4TextAreaValueChanged, true);
            app.val5TextArea = uitextarea(app.DIMENSIONSTab);
            app.val5TextArea.ValueChangedFcn = createCallbackFcn(app, @val5TextAreaValueChanged, true);
            app.stateLampLabel = uilabel(app.ACVproject);
            app.stateLampLabel.Text = 'state';
            app.stateLamp = uilamp(app.ACVproject);
            app.PLAYButton = uibutton(app.ACVproject, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.Switch = uiswitch(app.ACVproject, 'slider');
            app.Switch.Items = {'2D', '3D'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '2D';
            app.ACVPROJECTLabel = uilabel(app.ACVproject);
            app.ACVPROJECTLabel.Text = 'ACV PROJECT';
            app.messaggiTextArea = uitextarea(app.ACVproject);
            app.messaggiTextArea.ValueChangedFcn = createCallbackFcn(app, @messaggiTextAreaValueChanged, true);
            app.Panel = uipanel(app.ACVproject);
            app.DESIGNOBSTACLEButton = uibutton(app.Panel, 'push');
            app.DESIGNOBSTACLEButton.ButtonPushedFcn = createCallbackFcn(app, @DESIGNOBSTACLEButtonPushed, true);
            app.DESIGNOBSTACLEButton.Text = 'DESIGN OBSTACLE';
            app.LOADOBSTACLEButton = uibutton(app.Panel, 'push');
            app.LOADOBSTACLEButton.ButtonPushedFcn = createCallbackFcn(app, @LOADOBSTACLEButtonPushed, true);
            app.LOADOBSTACLEButton.Text = 'LOAD OBSTACLE';
            app.LOADICSButton = uibutton(app.Panel, 'push');
            app.LOADICSButton.ButtonPushedFcn = createCallbackFcn(app, @LOADICSButtonPushed, true);
            app.LOADICSButton.Text = 'LOAD ICS';
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.FATOBSTACLEButton = uibutton(app.Panel, 'push');
            app.FATOBSTACLEButton.ButtonPushedFcn = createCallbackFcn(app, @FATOBSTACLEButtonPushed, true);
            app.FATOBSTACLEButton.Text = 'FAT OBSTACLE';
            app.CheckBox_2 = uicheckbox(app.ACVproject);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.Button = uibutton(app.ACVproject, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.OBSButton = uibutton(app.ACVproject, 'push');
            app.OBSButton.ButtonPushedFcn = createCallbackFcn(app, @OBSButtonPushed, true);
            app.OBSButton.Text = 'OBS';
            app.TERMINAPanel = uipanel(app.ACVproject);
            app.TERMINAPanel.Title = 'TERMINA';
            app.XYCheckBox = uicheckbox(app.TERMINAPanel);
            app.XYCheckBox.ValueChangedFcn = createCallbackFcn(app, @XYCheckBoxValueChanged, true);
            app.XYCheckBox.Text = 'XY';
            app.AreaCheckBox = uicheckbox(app.TERMINAPanel);
            app.AreaCheckBox.ValueChangedFcn = createCallbackFcn(app, @AreaCheckBoxValueChanged, true);
            app.AreaCheckBox.Text = 'Area';
            app.XbCheckBox = uicheckbox(app.TERMINAPanel);
            app.XbCheckBox.ValueChangedFcn = createCallbackFcn(app, @XbCheckBoxValueChanged, true);
            app.XbCheckBox.Text = 'Xb';
            app.NpuntiCheckBox = uicheckbox(app.TERMINAPanel);
            app.NpuntiCheckBox.ValueChangedFcn = createCallbackFcn(app, @NpuntiCheckBoxValueChanged, true);
            app.NpuntiCheckBox.Text = 'Npunti';
            % Show the figure after all components are created
            app.ACVproject.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ACV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ACVproject)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ACVproject)
        end
    end
end